classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        PredictLabel      matlab.ui.control.Label
        GroundtruthLabel  matlab.ui.control.Label
        CAMButton         matlab.ui.control.Image
        DetectButton      matlab.ui.control.Image
        SearchButton      matlab.ui.control.Image
        SelectButton      matlab.ui.control.Image
        NotexistLabel     matlab.ui.control.Label
        Label             matlab.ui.control.Label
        WindowEditField   matlab.ui.control.EditField
        SubjectEditField  matlab.ui.control.EditField
        Image2            matlab.ui.control.Image
        Panel_2           matlab.ui.container.Panel
        UIAxes_2          matlab.ui.control.UIAxes
        Panel             matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        Image             matlab.ui.control.Image
    end

    
    properties (Access = public)
        guiPath
        subjectNum
        winNum
        dataTest
        im
        imHi
        detector
        softmax_layer
        activation_layer
        roi2soft_net
        selectIndexImg
        selectedBboxes
        selectedScores
        selectedLabels
        f1
        f2
    end
    
    methods (Access = public)
        
        function app = loadData(app)
            load([app.guiPath, '\bbox\', 's', app.subjectNum, '\', 'T_s', app.subjectNum, '.mat']);
                
            imdsTest = imageDatastore(T{:,'imageFilename'});
            bldsTest = boxLabelDatastore(T(:,2:6+1));
            app.dataTest = combine(imdsTest,bldsTest);
        end
    end
    
    methods (Access = private)
        
        function app = plotSignal(app)
             % Plot signal
            imagePath = [app.guiPath, '\signal\signal_10sec\s',...
                app.subjectNum, '\s', app.subjectNum, '_', app.winNum,'.png'];
            imagePathHi = [app.guiPath, '\signal_hiRes\signal_10sec\s',...
                app.subjectNum, '\s', app.subjectNum, '_', app.winNum,'.png'];
            app.im = imread(imagePath);
            app.imHi = imread(imagePathHi);
            app.f1 = imshow(app.imHi, 'Parent', app.UIAxes);

            % Plot ground truth
            RGB = plot_ground_truth_new(app.imHi, [227, 681, 3], app.dataTest, str2num(app.winNum));
            app.f1 = imshow(RGB, 'Parent', app.UIAxes);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath('imgs'))
            addpath(genpath('functions'))

            app.guiPath = fileparts(mfilename('fullpath'));
            load([app.guiPath, '\trainedNet\', 'resnet50.mat']);
            app.detector = detector;
            net_used = 'resnet50';
            roi2soft_layers = [ ...
                imageInputLayer([8 22 2048], 'Name', 'input_roi', 'Normalization', 'none')
                app.detector.Network.Layers(180:182)
                ];
            app.softmax_layer = 'rcnnSoftmax';
            app.activation_layer = 'activation_49_relu';
            app.roi2soft_net = dlnetwork(layerGraph(roi2soft_layers));
            disp('================== GUI Start! =======================')

        end

        % Callback function
        function ButtonPushed(app, event)

        end

        % Callback function
        function DetectButtonPushed(app, event)
   
        end

        % Image clicked function: SelectButton
        function SelectButtonClicked(app, event)
            [file,path] = uigetfile('*.png',...
               'Select an ECG signal', [app.guiPath, '\signal\signal_10sec']);
            if isequal(file,0)
                disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(path,file)]);
                app.subjectNum = file(2:4);
                app.winNum = file(6:end-4);
                
                app.SubjectEditField.Value = app.subjectNum;
                app.WindowEditField.Value = app.winNum;
                app = app.loadData();
                app = app.plotSignal();
                
                app.Panel.Visible = 'on';
                app.DetectButton.Visible = 'on';
                app.CAMButton.Visible = 'off';
                app.Label.Text = '';
                app.Image2.Visible = 'off';
                app.GroundtruthLabel.Visible = 'on';
                app.PredictLabel.Visible = 'off';
            end
        end

        % Image clicked function: SearchButton
        function SearchButtonClicked(app, event)
            searchSubjectFolder = [app.guiPath, '\bbox\', 's', app.SubjectEditField.Value];
            searchWindow = str2num(app.WindowEditField.Value);
            if not(isfolder(searchSubjectFolder))
                disp(['Subject ', app.SubjectEditField.Value, ' not exist'])
                app.NotexistLabel.Text = ['Subject ', app.SubjectEditField.Value, ' not exist'];
                app.NotexistLabel.Visible = 'on';
                app.SubjectEditField.Value = '';
                app.WindowEditField.Value = '';
            elseif isempty(searchWindow) | searchWindow > 180 | searchWindow < 1
                disp(['Window ', app.WindowEditField.Value, ' not exist'])
                app.NotexistLabel.Text = ['Window ', app.WindowEditField.Value, ' not exist'];
                app.NotexistLabel.Visible = 'on';
                app.WindowEditField.Value = '';
            else
                app.subjectNum = app.SubjectEditField.Value;
                app.winNum = app.WindowEditField.Value;
                app = app.loadData();
                app = app.plotSignal();
                
                app.DetectButton.Visible = 'on';
                app.CAMButton.Visible = 'off';
                app.f2.Visible = 'off';
                app.NotexistLabel.Visible = 'off';
                app.Label.Text = '';
                app.Image2.Visible = 'off';
                app.GroundtruthLabel.Visible = 'on';
                app.PredictLabel.Visible = 'off';
            end
        end

        % Image clicked function: DetectButton
        function DetectButtonImageClicked(app, event)
            disp('Detecting...')
            [detectBoxImg, detectScoreImg, detectClassImg] = detect(app.detector,app.im,...
                    'Threshold', 0.5, 'SelectStrongest', false, 'MiniBatchSize', 1);
            [app.selectedBboxes, app.selectedScores, app.selectedLabels, app.selectIndexImg] = selectStrongestBboxMulticlass(...
                detectBoxImg, detectScoreImg, detectClassImg, 'OverlapThreshold', 0.2);
            
            % Plot predict box
            [RGB, bboxLabel] = plot_predict_bbox_new(app.imHi, app.selectedScores, app.selectedLabels, app.selectedBboxes, 0.5);
            app.f2 = imshow(RGB, 'Parent', app.UIAxes_2);
            % Calculate each heart beat number
            keySet = {'SR', 'APC', 'VPC', 'LBBB', 'RBBB', 'Others'};
            labelCount = [0, 0, 0, 0, 0, 0];
            M = containers.Map(keySet, labelCount);
            for i = 1:length(bboxLabel)
                thisLabel = char(bboxLabel(i));
                M(thisLabel) = M(thisLabel) + 1;
            end
            textValue = {};
            for i = 1:length(keySet)
                thisKey = keySet{i};
                thisValue = M(keySet{i});
                if thisValue > 0
                    textValue = [textValue; [thisKey, ': ', int2str(thisValue)]];
                end
            end
            textValue
            app.Label.Text = textValue;
            app.Image2.Visible = 'on';
            app.f2.Visible = 'on';
            app.CAMButton.Visible = 'on';
            app.PredictLabel.Visible = 'on';
        end

        % Image clicked function: CAMButton
        function CAMButtonImageClicked(app, event)
            % CAM
            combinedImage = plotGradCam(app.im, app.imHi, app.detector, app.roi2soft_net, app.activation_layer, app.softmax_layer, app.selectIndexImg, app.selectedBboxes, 0.5, 1);
            RGB = plot_predict_bbox_new(combinedImage, app.selectedScores, app.selectedLabels, app.selectedBboxes, 0.5);
            app.f2 = imshow(RGB, 'Parent', app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'GUI_01-1.png';
            app.Panel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.Panel);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.Panel_2 = uipanel(app.UIFigure);
            app.UIAxes_2 = uiaxes(app.Panel_2);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.Visible = 'off';
            app.Image2.ImageSource = 'GUI_03-3.png';
            app.SubjectEditField = uieditfield(app.UIFigure, 'text');
            app.WindowEditField = uieditfield(app.UIFigure, 'text');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.NotexistLabel = uilabel(app.UIFigure);
            app.NotexistLabel.Visible = 'off';
            app.NotexistLabel.Text = 'Not exist';
            app.SelectButton = uiimage(app.UIFigure);
            app.SelectButton.ImageClickedFcn = createCallbackFcn(app, @SelectButtonClicked, true);
            app.SelectButton.ImageSource = 'GUI_01-2(1).png';
            app.SearchButton = uiimage(app.UIFigure);
            app.SearchButton.ImageClickedFcn = createCallbackFcn(app, @SearchButtonClicked, true);
            app.SearchButton.ImageSource = 'GUI_01-2.png';
            app.DetectButton = uiimage(app.UIFigure);
            app.DetectButton.ImageClickedFcn = createCallbackFcn(app, @DetectButtonImageClicked, true);
            app.DetectButton.Visible = 'off';
            app.DetectButton.ImageSource = 'GUI_03-1.png';
            app.CAMButton = uiimage(app.UIFigure);
            app.CAMButton.ImageClickedFcn = createCallbackFcn(app, @CAMButtonImageClicked, true);
            app.CAMButton.Visible = 'off';
            app.CAMButton.ImageSource = 'GUI_03-2.png';
            app.GroundtruthLabel = uilabel(app.UIFigure);
            app.GroundtruthLabel.Visible = 'off';
            app.GroundtruthLabel.Text = 'Ground truth';
            app.PredictLabel = uilabel(app.UIFigure);
            app.PredictLabel.Visible = 'off';
            app.PredictLabel.Text = 'Predict';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end